classdef PlaneIntersection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Plane1_a              matlab.ui.control.NumericEditField
        Plane1_b              matlab.ui.control.NumericEditField
        Plane1_c              matlab.ui.control.NumericEditField
        Plane2_a              matlab.ui.control.NumericEditField
        Plane2_b              matlab.ui.control.NumericEditField
        Plane2_c              matlab.ui.control.NumericEditField
        Plane3_a              matlab.ui.control.NumericEditField
        Plane3_b              matlab.ui.control.NumericEditField
        Plane3_c              matlab.ui.control.NumericEditField
        GeneralEquationLabel  matlab.ui.control.Label
        EquationPlane1        matlab.ui.control.Label
        Plane1_d              matlab.ui.control.NumericEditField
        Plane2_d              matlab.ui.control.NumericEditField
        Plane3_d              matlab.ui.control.NumericEditField
        XLabel                matlab.ui.control.Label
        XLabel_2              matlab.ui.control.Label
        XLabel_3              matlab.ui.control.Label
        YLabel                matlab.ui.control.Label
        YLabel_2              matlab.ui.control.Label
        YLabel_3              matlab.ui.control.Label
        ZLabel                matlab.ui.control.Label
        ZLabel_2              matlab.ui.control.Label
        ZLabel_3              matlab.ui.control.Label
        Label                 matlab.ui.control.Label
        Label_2               matlab.ui.control.Label
        Label_3               matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
        GraphStatusLamp       matlab.ui.control.Lamp
        SolutionLabel         matlab.ui.control.Label
        NoSolutionLampLabel   matlab.ui.control.Label
        NoSolutionLamp        matlab.ui.control.Lamp
        PointLampLabel        matlab.ui.control.Label
        PointLamp             matlab.ui.control.Lamp
        LineLampLabel         matlab.ui.control.Label
        LineLamp              matlab.ui.control.Lamp
        PlaneLampLabel        matlab.ui.control.Label
        PlaneLamp             matlab.ui.control.Lamp
        Label_4               matlab.ui.control.Label
        Label_5               matlab.ui.control.Label
        Label_6               matlab.ui.control.Label
        Det123Label           matlab.ui.control.Label
        GeneralInfoLabel      matlab.ui.control.Label
        Determinant           matlab.ui.control.Label
        Label_7               matlab.ui.control.Label
        Label_8               matlab.ui.control.Label
        Label_9               matlab.ui.control.Label
        Label_10              matlab.ui.control.Label
        Label_11              matlab.ui.control.Label
        Label_12              matlab.ui.control.Label
        pi1pi2_p              matlab.ui.control.Label
        pi2pi3_p              matlab.ui.control.Label
        pi3pi1_p              matlab.ui.control.Label
        pi1pi2_c              matlab.ui.control.Label
        pi2pi3_c              matlab.ui.control.Label
        pi3pi1_c              matlab.ui.control.Label
    end

    
    properties (Access = private)
        GraphPlanes % Graph of planes
        canPlot % If is possible to plot
        d123
        p12
        p23
        p31
        c12
        c23
        c31
    end
    
    
    methods (Access = private)
% ------------------------------------------------ Plane Coordinates
        function [x,y,z] = planeCord(app,a,b,c,d)
            if(c~=0)
                ax = linspace(-10,10);
                ay = linspace(-10,10);
                [x,y]=meshgrid(ax,ay);
                z = (-a*x-b*y-d)/c;
            elseif(b~=0)
                ax = linspace(-10,10);
                az = linspace(-10,10);
                [x,z]=meshgrid(ax,az);
                y = (-a*x-c*z-d)/b; 
            elseif(a~=0)
                ay = linspace(-10,10);
                az = linspace(-10,10);
                [y,z]=meshgrid(ay,az);
                x = (-b*y-c*z-d)/a;   
            else
                x=0;
                y=0;
                z=0;
                app.canPlot=0;
            end
        end
% ------------------------------------------------ Plot Planes
        function [] = plotPlanes(app)
            app.canPlot=1;
            for i=1:3
                num = num2str(i);
                eval(['[x' num ',y' num ',z' num '] = app.planeCord(app.Plane' num '_a.Value,app.Plane' num '_b.Value,app.Plane' num '_c.Value,app.Plane' num '_d.Value);']);
            end
            if(app.canPlot==1)
                app.GraphStatusLamp.Color=[0 1 0];
                cla(app.UIAxes);
                
                surf(app.UIAxes,x1,y1,z1,'EdgeColor',"none","FaceColor",[0.5 0 0],"FaceAlpha",0.3);
                hold(app.UIAxes,'on');
                surf(app.UIAxes,x2,y2,z2,'EdgeColor',"none","FaceColor",[0 0.5 0],"FaceAlpha",0.3);
                surf(app.UIAxes,x3,y3,z3,'EdgeColor',"none","FaceColor",[0 0 0.5],"FaceAlpha",0.3);
                xlim(app.UIAxes,[-10 10]);
                ylim(app.UIAxes,[-10 10]);
                zlim(app.UIAxes,[-10 10]);

                app.checkSolution();
            else
                app.GraphStatusLamp.Color=[1 0 0];
                app.NoSolutionLamp.Color=[.65 .65 .65];
                app.PointLamp.Color=[.65 .65 .65];
                app.LineLamp.Color=[.65 .65 .65];
                app.PlaneLamp.Color=[.65 .65 .65];
            end
           
        end
% ------------------------------------------------ Check Solutions
        function [] = checkSolution(app)
            for i=1:3
                num = num2str(i);
                eval(['a' num ' = app.Plane' num '_a.Value;']);
                eval(['b' num ' = app.Plane' num '_b.Value;']);
                eval(['c' num ' = app.Plane' num '_c.Value;']);
                eval(['d' num ' = app.Plane' num '_d.Value;']);
            end
            
%             disp(['dist 1: ' num2str(app.distPLanePoint(a1,b1,c1,d1,0,0,0))]);
%             disp(['angle Z=0: ' num2str(180/pi*app.angleTwoPlanes(a1,b1,c1,0,0,1))]);
%             disp(['angle Z=0: ' num2str(180/pi*app.angleTwoPlanes(a1,b1,c1,0,0,1))]);
            
           app.d123 = det([a1,b1,c1;a2,b2,c2;a3,b3,c3]);
           app.p12 = app.detectParallelism(a1,b1,c1,a2,b2,c2);
           app.p23 = app.detectParallelism(a2,b2,c2,a3,b3,c3);
           app.p31 = app.detectParallelism(a3,b3,c3,a1,b1,c1);
           
           app.Determinant.Text=num2str(app.d123>=0.0001);
           app.pi1pi2_p.Text=num2str(app.p12);
           app.pi2pi3_p.Text=num2str(app.p23);
           app.pi3pi1_p.Text=num2str(app.p31);
           
           % ----- (Start) variables to coincident planes
           app.c12=false;auxc12=[-1 -1 -1 -1];
           app.c23=false;auxc23=[-1 -1 -1 -1];
           app.c31=false;auxc31=[-1 -1 -1 -1];
           
          letterAux=['a' 'b' 'c' 'd'];
          for i=[12,23,31]
            for j=1:4
               if( eval([letterAux(j) num2str(floor(i/10)) '~=0 && ' letterAux(j) num2str(mod(i,10)) '~=0']) )% a1~=0 && a2~=0
                eval(['auxc' num2str(i) '(' num2str(j) ')=' letterAux(j) num2str(floor(i/10)) '/' letterAux(j) num2str(mod(i,10)) ';']);% auxc12(1)=b1/b2;
               elseif( eval([ letterAux(j) num2str(floor(i/10)) '==0 && ' letterAux(j) num2str(mod(i,10)) '==0']) )% a1==0 && a2==0
                  eval(['auxc' num2str(i) '(' num2str(j) ')=0;']);% auxc12(1)=0;
               end
            end
          end

           for i=[12,23,31]
               iStr=num2str(i);
               if(eval([ 'auxc' iStr '(1)==-1 || auxc' iStr '(2)==-1 || auxc' iStr '(3)==-1 || auxc' iStr '(4)==-1' ]))% auxc12(1)==-1 || auxc12(2)==-1 || auxc12(3)==-1 || auxc12(4)==-1
                   eval(['app.c' iStr '=false;']);% c12=false;
               else
                   k=[];
                  for j=1:4
                      if(eval(['auxc' iStr '(j)~=0']))% auxc12(j)~=0
                          k = [k eval(['auxc' iStr '(j)'])];% auxc12(j)
                      end
                  end
                  if(max(k)==min(k))
                      eval(['app.c' iStr '=true;']);% c12=true;
                  end
               end
           end
           % ----- (End) variables to coincident planes
           
           app.pi1pi2_c.Text=num2str(app.c12);
           app.pi2pi3_c.Text=num2str(app.c23);
           app.pi3pi1_c.Text=num2str(app.c31);
           
           rd = rank([a1,b1,c1,d1;a2,b2,c2,d2;a3,b3,c3,d3]);
           
           if(abs(app.d123)>=0.0001)%123 123LI
               app.pointSolution();
           else% LD
                if(app.c12==1 && app.c23==1 && app.c31==1)% all three on the same plane
                    app.planeSolution();
                elseif(app.p12==1 && app.p23==0 && app.p31==0)% only 1 and 2 parallel
                    if(app.c12==1)% 1 and 2 on the same plane
                       app.lineSolution();
                    else% two are parallel but not coincident
                        app.noSolution();
                    end
                elseif(app.p12==0 && app.p23==1 && app.p31==0)% only 2 and 3 parallel
                    if(app.c23==1)% 2 and 3 on the same plane
                       app.lineSolution();
                    else% two are parallel but not coincident
                        app.noSolution();
                    end   
                elseif(app.p12==0 && app.p23==0 && app.p31==1)% only 3 and 1 parallel
                    if(app.c31==1)% 3 and 1 on the same plane
                       app.lineSolution();
                    else% two are parallel but not coincident
                        app.noSolution();
                    end
                elseif(app.p12==0 && app.p23==0 && app.p31==0)% no parallelism
                    if(rd==3)
                    app.noSolution();
                    elseif(rd==2)
                    app.lineSolution();
                    end
                else% planes: | | |
                    app.noSolution();
                end
           end
        end
% ------------------------------------------------ No solution
        function [] = noSolution(app)
            app.NoSolutionLamp.Color=[0 1 0];
            app.PointLamp.Color=[.65 .65 .65];
            app.LineLamp.Color=[.65 .65 .65];
            app.PlaneLamp.Color=[.65 .65 .65];
        end
% ------------------------------------------------ Plot Point Solution
        function [] = pointSolution(app)
            app.NoSolutionLamp.Color=[.65 .65 .65];
            app.PointLamp.Color=[0 1 0];
            app.LineLamp.Color=[.65 .65 .65];
            app.PlaneLamp.Color=[.65 .65 .65];
            
            for i=1:3
                num = num2str(i);
                eval(['a' num ' = app.Plane' num '_a.Value;']);
                eval(['b' num ' = app.Plane' num '_b.Value;']);
                eval(['c' num ' = app.Plane' num '_c.Value;']);
                eval(['d' num ' = app.Plane' num '_d.Value;']);
            end
            
            m=[a1,b1,c1;a2,b2,c2;a3,b3,c3];
            m=inv(m);
            s=[-d1;-d2;-d3];
            x=s(1)*m(1,1)+s(2)*m(1,2)+s(3)*m(1,3);
            y=s(1)*m(2,1)+s(2)*m(2,2)+s(3)*m(2,3);
            z=s(1)*m(3,1)+s(2)*m(3,2)+s(3)*m(3,3);
            surf(app.UIAxes,[x,x;x x],[y,y;y,y],[z,z;z,z],'Marker',"o",'MarkerSize',10,'MarkerEdgeColor','none',"MarkerFaceColor",[0 1 0]);
        end
% ------------------------------------------------ Plot Line Solution
        function [] = lineSolution(app)
            app.NoSolutionLamp.Color=[.65 .65 .65];
            app.PointLamp.Color=[.65 .65 .65];
            app.LineLamp.Color=[0 1 0];
            app.PlaneLamp.Color=[.65 .65 .65];
            
            for i=1:3
                num = num2str(i);
                eval(['a' num ' = app.Plane' num '_a.Value;']);
                eval(['b' num ' = app.Plane' num '_b.Value;']);
                eval(['c' num ' = app.Plane' num '_c.Value;']);
                eval(['d' num ' = app.Plane' num '_d.Value;']);
            end
            disp(['1: ' num2str(a1) ' ' num2str(b1) ' ' num2str(c1)]);
            disp(['2: ' num2str(a2) ' ' num2str(b2) ' ' num2str(c2)]);
            % vector product
            if(app.c12==0)
                [vx,vy,vz]=app.vectorProduct(a1,b1,c1,a2,b2,c2);
            elseif(app.c23==0)
                [vx,vy,vz]=app.vectorProduct(a2,b2,c2,a3,b3,c3);
            elseif(app.c31==0)
                [vx,vy,vz]=app.vectorProduct(a3,b3,c3,a1,b1,c1);
            end

            vx=vx*10;
            vy=vy*10;
            vz=vz*10;
            
            [shiftX,shiftY,shiftZ] = app.findPointLine()
            
            surf(app.UIAxes,[-vx+shiftX,vx+shiftX;-vx+shiftX,vx+shiftX],[-vy+shiftY,vy+shiftY;-vy+shiftY,vy+shiftY],[-vz+shiftZ,vz+shiftZ;-vz+shiftZ,vz+shiftZ],'EdgeColor','g','LineWidth',2);
            
        end
    
% ------------------------------------------------ Plot Plane Solution
        function [] = planeSolution(app)
            app.NoSolutionLamp.Color=[.65 .65 .65];
            app.PointLamp.Color=[.65 .65 .65];
            app.LineLamp.Color=[.65 .65 .65];
            app.PlaneLamp.Color=[0 1 0];
            
            
            num = num2str(1);
            eval(['[x' num ',y' num ',z' num '] = app.planeCord(app.Plane' num '_a.Value,app.Plane' num '_b.Value,app.Plane' num '_c.Value,app.Plane' num '_d.Value);']);
            
            surf(app.UIAxes,x1,y1,z1,'EdgeColor',"none","FaceColor",[0 1 0]);      
        end
% ------------------------------------------------ Detect Parallelism
        function [r,relation] = detectParallelism(app,a1,b1,c1,a2,b2,c2)
           r=false;auxr=[-1 -1 -1];relation=0;
           
          letterAux=['a' 'b' 'c'];
          for i=12
            for j=1:3
               if( eval([letterAux(j) num2str(floor(i/10)) '~=0 && ' letterAux(j) num2str(mod(i,10)) '~=0']) )% a1~=0 && a2~=0
                eval(['auxr' '(' num2str(j) ')=' letterAux(j) num2str(floor(i/10)) '/' letterAux(j) num2str(mod(i,10)) ';']);% auxc12(1)=b1/b2;
               elseif( eval([ letterAux(j) num2str(floor(i/10)) '==0 && ' letterAux(j) num2str(mod(i,10)) '==0']) )% a1==0 && a2==0
                  eval(['auxr' '(' num2str(j) ')=0;']);% auxc12(1)=0;
               end
            end
          end

           for i=12
               if(auxr(1)==-1 || auxr(2)==-1 || auxr(3)==-1)% auxc12(1)==-1 || auxc12(2)==-1 || auxc12(3)==-1 || auxc12(4)==-1
                   r=false;
               else
                   k=[];
                  for j=1:3
                      if(eval(['auxr(j)~=0']))% auxc12(j)~=0
                          k = [k eval(['auxr(j)'])];% auxc12(j)
                      end
                  end
                  if(max(k)==min(k))
                      r=true;
                      relation=k;
                  end
               end
           end
        end
% ------------------------------------------------ Vector Product
        function [v1,v2,v3] = vectorProduct(app,a1,b1,c1,a2,b2,c2)
            v1=(b1*c2-b2*c1);
%             disp(['v2= ' num2str(c1) '*' num2str(a2) '-' num2str(a1) '*' num2str(c2)]);
            v2=(c1*a2-a1*c2);
            v3=(a1*b2-a2*b1);
        end
    
% ------------------------------------------------ Point to Plane
        function [dist] = distPLanePoint(app,a,b,c,d,xo,yo,zo)
            dist = abs(a*xo+b*yo+c*zo+d)/sqrt(a*a+b*b+c*c);
        end
% ------------------------------------------------ Angle two planes
        function [angle] = angleTwoPlanes(app,a1,b1,c1,a2,b2,c2)
            angle = acos( abs(a1*a2+b1*b2+c1*c2)/(sqrt(a1*a1+b1*b1+c1*c1)*sqrt(a2*a2+b2*b2+c2*c2)) );
        end
% ------------------------------------------------ norma vetor
        function [angle] = norma(app,x,y,z)
            angle = sqrt(x*x+y*y+z*z);
        end
% ------------------------------------------------ Plot Point Solution
        function [x,y,z] = findPointLine(app)
            x=0;
            y=0;
            z=0;
        for i=1:3
            num = num2str(i);
            eval(['a' num ' = app.Plane' num '_a.Value;']);
            eval(['b' num ' = app.Plane' num '_b.Value;']);
            eval(['c' num ' = app.Plane' num '_c.Value;']);
            eval(['d' num ' = app.Plane' num '_d.Value;']);
        end
        
%         addTo = {'a1','b1','c1','a2','b2','c2','a3','b3','c3'}
        for addTo={'a1','b1','c1','a2','b2','c2','a3','b3','c3'}
            
            eval([ char(addTo) '=' char(addTo) '+1;']);
            determinante = det([a1,b1,c1;a2,b2,c2;a3,b3,c3]);
            
           if(determinante~=0)
            m=[a1,b1,c1;a2,b2,c2;a3,b3,c3];
            m=inv(m);
            s=[-d1;-d2;-d3];
            x=s(1)*m(1,1)+s(2)*m(1,2)+s(3)*m(1,3);
            y=s(1)*m(2,1)+s(2)*m(2,2)+s(3)*m(2,3);
            z=s(1)*m(3,1)+s(2)*m(3,2)+s(3)*m(3,3);
%             surf(app.UIAxes,[x,x;x x],[y,y;y,y],[z,z;z,z],'Marker',"o",'MarkerSize',10,'MarkerEdgeColor','none',"MarkerFaceColor",[0 1 0]);

               break
           end
           eval([ char(addTo) '=' char(addTo) '-1;']);
        end
    end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
           app.UIAxes.PlotBoxAspectRatio = [1 1 1];
           plotPlanes(app);
        end

        % Value changed function: Plane1_a
        function Plane1_aValueChanged(app, event)
%             value = app.Plane1_a.Value;
            plotPlanes(app);
        end

        % Value changed function: Plane1_b
        function Plane1_bValueChanged(app, event)
%             value = app.Plane1_b.Value;
            plotPlanes(app);
        end

        % Value changed function: Plane1_c
        function Plane1_cValueChanged(app, event)
%             value = app.Plane1_c.Value;
            plotPlanes(app);
        end

        % Value changed function: Plane1_d
        function Plane1_dValueChanged(app, event)
%             value = app.Plane1_d.Value;
            plotPlanes(app);
        end

        % Value changed function: Plane2_a
        function Plane2_aValueChanged(app, event)
%             value = app.Plane2_a.Value;
            plotPlanes(app);
        end

        % Value changed function: Plane2_b
        function Plane2_bValueChanged(app, event)
%             value = app.Plane2_b.Value;
            plotPlanes(app);
        end

        % Value changed function: Plane2_c
        function Plane2_cValueChanged(app, event)
%             value = app.Plane2_c.Value;
            plotPlanes(app);
        end

        % Value changed function: Plane2_d
        function Plane2_dValueChanged(app, event)
%             value = app.Plane2_d.Value;
            plotPlanes(app);
        end

        % Value changed function: Plane3_a
        function Plane3_aValueChanged(app, event)
%             value = app.Plane3_a.Value;
            plotPlanes(app);
        end

        % Value changed function: Plane3_b
        function Plane3_bValueChanged(app, event)
%             value = app.Plane3_b.Value;
            plotPlanes(app);
        end

        % Value changed function: Plane3_c
        function Plane3_cValueChanged(app, event)
%             value = app.Plane3_c.Value;
            plotPlanes(app);
        end

        % Value changed function: Plane3_d
        function Plane3_dValueChanged(app, event)
%             value = app.Plane3_d.Value;
            plotPlanes(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Plane1_a = uieditfield(app.UIFigure, 'numeric');
            app.Plane1_a.ValueChangedFcn = createCallbackFcn(app, @Plane1_aValueChanged, true);
            app.Plane1_a.Value = 1;
            app.Plane1_b = uieditfield(app.UIFigure, 'numeric');
            app.Plane1_b.ValueChangedFcn = createCallbackFcn(app, @Plane1_bValueChanged, true);
            app.Plane1_c = uieditfield(app.UIFigure, 'numeric');
            app.Plane1_c.ValueChangedFcn = createCallbackFcn(app, @Plane1_cValueChanged, true);
            app.Plane2_a = uieditfield(app.UIFigure, 'numeric');
            app.Plane2_a.ValueChangedFcn = createCallbackFcn(app, @Plane2_aValueChanged, true);
            app.Plane2_b = uieditfield(app.UIFigure, 'numeric');
            app.Plane2_b.ValueChangedFcn = createCallbackFcn(app, @Plane2_bValueChanged, true);
            app.Plane2_b.Value = 1;
            app.Plane2_c = uieditfield(app.UIFigure, 'numeric');
            app.Plane2_c.ValueChangedFcn = createCallbackFcn(app, @Plane2_cValueChanged, true);
            app.Plane3_a = uieditfield(app.UIFigure, 'numeric');
            app.Plane3_a.ValueChangedFcn = createCallbackFcn(app, @Plane3_aValueChanged, true);
            app.Plane3_b = uieditfield(app.UIFigure, 'numeric');
            app.Plane3_b.ValueChangedFcn = createCallbackFcn(app, @Plane3_bValueChanged, true);
            app.Plane3_c = uieditfield(app.UIFigure, 'numeric');
            app.Plane3_c.ValueChangedFcn = createCallbackFcn(app, @Plane3_cValueChanged, true);
            app.Plane3_c.Value = 1;
            app.GeneralEquationLabel = uilabel(app.UIFigure);
            app.GeneralEquationLabel.Text = 'General Equation';
            app.EquationPlane1 = uilabel(app.UIFigure);
            app.EquationPlane1.Text = '( aX + bY + cY + d = 0 )';
            app.Plane1_d = uieditfield(app.UIFigure, 'numeric');
            app.Plane1_d.ValueChangedFcn = createCallbackFcn(app, @Plane1_dValueChanged, true);
            app.Plane2_d = uieditfield(app.UIFigure, 'numeric');
            app.Plane2_d.ValueChangedFcn = createCallbackFcn(app, @Plane2_dValueChanged, true);
            app.Plane3_d = uieditfield(app.UIFigure, 'numeric');
            app.Plane3_d.ValueChangedFcn = createCallbackFcn(app, @Plane3_dValueChanged, true);
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X +';
            app.XLabel_2 = uilabel(app.UIFigure);
            app.XLabel_2.Text = 'X +';
            app.XLabel_3 = uilabel(app.UIFigure);
            app.XLabel_3.Text = 'X +';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y +';
            app.YLabel_2 = uilabel(app.UIFigure);
            app.YLabel_2.Text = 'Y +';
            app.YLabel_3 = uilabel(app.UIFigure);
            app.YLabel_3.Text = 'Y +';
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z +';
            app.ZLabel_2 = uilabel(app.UIFigure);
            app.ZLabel_2.Text = 'Z +';
            app.ZLabel_3 = uilabel(app.UIFigure);
            app.ZLabel_3.Text = 'Z +';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '= 0';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '= 0';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '= 0';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.GraphStatusLamp = uilamp(app.UIFigure);
            app.SolutionLabel = uilabel(app.UIFigure);
            app.SolutionLabel.Text = 'Solution';
            app.NoSolutionLampLabel = uilabel(app.UIFigure);
            app.NoSolutionLampLabel.Text = 'No Solution';
            app.NoSolutionLamp = uilamp(app.UIFigure);
            app.PointLampLabel = uilabel(app.UIFigure);
            app.PointLampLabel.Text = 'Point';
            app.PointLamp = uilamp(app.UIFigure);
            app.LineLampLabel = uilabel(app.UIFigure);
            app.LineLampLabel.Text = 'Line';
            app.LineLamp = uilamp(app.UIFigure);
            app.PlaneLampLabel = uilabel(app.UIFigure);
            app.PlaneLampLabel.Text = 'Plane';
            app.PlaneLamp = uilamp(app.UIFigure);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'π1:';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'π2:';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'π3:';
            app.Det123Label = uilabel(app.UIFigure);
            app.Det123Label.Text = 'Det (π1,π2,π3):';
            app.GeneralInfoLabel = uilabel(app.UIFigure);
            app.GeneralInfoLabel.Text = 'General Info';
            app.Determinant = uilabel(app.UIFigure);
            app.Determinant.Text = '0';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = 'π1||π2:';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = 'π2||π3:';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = 'π3||π1:';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = 'π1=π2:';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = 'π2=π3:';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = 'π3=π1:';
            app.pi1pi2_p = uilabel(app.UIFigure);
            app.pi1pi2_p.Text = '0';
            app.pi2pi3_p = uilabel(app.UIFigure);
            app.pi2pi3_p.Text = '0';
            app.pi3pi1_p = uilabel(app.UIFigure);
            app.pi3pi1_p.Text = '0';
            app.pi1pi2_c = uilabel(app.UIFigure);
            app.pi1pi2_c.Text = '0';
            app.pi2pi3_c = uilabel(app.UIFigure);
            app.pi2pi3_c.Text = '0';
            app.pi3pi1_c = uilabel(app.UIFigure);
            app.pi3pi1_c.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlaneIntersection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end